classdef ICK_SENSORS_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Image                    matlab.ui.control.Image
        RatioLabel               matlab.ui.control.Label
        Label_9                  matlab.ui.control.Label
        Lamp_2                   matlab.ui.control.Lamp
        Lamp_2Label              matlab.ui.control.Label
        Lamp                     matlab.ui.control.Lamp
        Label_7                  matlab.ui.control.Label
        STOPButton               matlab.ui.control.Button
        SterowanieLabel          matlab.ui.control.Label
        Label_6                  matlab.ui.control.Label
        Label_5                  matlab.ui.control.Label
        PozycjaLabel             matlab.ui.control.Label
        Label_4                  matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        ADRESURIEditField        matlab.ui.control.EditField
        ADRESURIEditFieldLabel   matlab.ui.control.Label
        KOLOR2MAXEditField       matlab.ui.control.NumericEditField
        KOLOR2MAXEditFieldLabel  matlab.ui.control.Label
        KOLOR2MINEditField       matlab.ui.control.NumericEditField
        KOLOR2MINEditFieldLabel  matlab.ui.control.Label
        KOLOR1MINEditField       matlab.ui.control.NumericEditField
        KOLOR1MINEditFieldLabel  matlab.ui.control.Label
        KOLOR1MAXEditField       matlab.ui.control.NumericEditField
        KOLOR1MAXEditFieldLabel  matlab.ui.control.Label
        DEADZONEEditField        matlab.ui.control.NumericEditField
        DEADZONEEditFieldLabel   matlab.ui.control.Label
        KAMERAButton             matlab.ui.control.Button
        ORIENTACJAButton         matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        m=mobiledev;
        stop_app=false'
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ORIENTACJAButton
        function ORIENTACJAButtonPushed(app, event)
            import matlab.net.http.*
            import matlab.net.http.field.* 
            
            control=1;
            while ~app.stop_app
                app.Lamp.Color="g";
                pitch=-app.m.Orientation(2);
                if pitch>app.DEADZONEEditField.Value
                    control=1;
                end
                if pitch<-app.DEADZONEEditField.Value
                    control=0;
                end
                app.Label_6.Text=num2str(control);
                app.Label_4.Text=num2str(pitch,'%.2f');
                pause(1)

                json= num2str(control,'{"id":3,"racketDirection":%i}');

                mb = MessageBody;
                mb.Payload = json;
                request = RequestMessage('POST', ...
                                [ContentTypeField('application/json')], ...
                                mb);
                request.send(app.ADRESURIEditField.Value);
            end
            app.stop_app=false;
            app.Lamp.Color="w";
            app.Label_4.Text=" ";
            app.Label_6.Text=" ";
        end

        % Button pushed function: KAMERAButton
        function KAMERAButtonPushed(app, event)
            import matlab.net.http.*
            import matlab.net.http.field.* 
            
            c=camera(app.m,"back");
            while ~app.stop_app
                app.Lamp_2.Color="g";
                I_camera=snapshot(c,'immediate');
                I_hsv=rgb2hsv(I_camera);
                Hue=I_hsv(:,:,1)*360;
                red= Hue < app.KOLOR1MAXEditField.Value | Hue>app.KOLOR1MINEditField.Value;
                cyan= Hue >app.KOLOR2MAXEditField.Value & Hue<app.KOLOR2MINEditField.Value;
                red_sum=sum(sum(red))+0.01;
                cyan_sum=sum(sum(cyan))+0.01;
                ratio=red_sum/cyan_sum;
                if ratio>1
                    control=1;
                else
                    control=0;
                end
                app.Label_6.Text=num2str(control);
                app.Label_9.Text=num2str(ratio,'%2.2f');
                close all
                imshow(I_camera,'Parent',app.UIAxes);
                pause(1)

                json= num2str(control,'{"id":3,"racketDirection":%i}');

                mb = MessageBody;
                mb.Payload = json;
                request = RequestMessage('POST', ...
                                [ContentTypeField('application/json')], ...
                                mb);
                request.send(app.ADRESURIEditField.Value);

            end
            app.stop_app=false;
            app.Lamp_2.Color="w";
            app.Label_9.Text=" ";
            app.Label_6.Text=" ";
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.stop_app=true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.ORIENTACJAButton = uibutton(app.UIFigure, 'push');
            app.ORIENTACJAButton.ButtonPushedFcn = createCallbackFcn(app, @ORIENTACJAButtonPushed, true);
            app.ORIENTACJAButton.Text = 'ORIENTACJA';
            app.KAMERAButton = uibutton(app.UIFigure, 'push');
            app.KAMERAButton.ButtonPushedFcn = createCallbackFcn(app, @KAMERAButtonPushed, true);
            app.KAMERAButton.Text = 'KAMERA';
            app.DEADZONEEditFieldLabel = uilabel(app.UIFigure);
            app.DEADZONEEditFieldLabel.Text = 'DEAD-ZONE';
            app.DEADZONEEditField = uieditfield(app.UIFigure, 'numeric');
            app.DEADZONEEditField.Value = 10;
            app.KOLOR1MAXEditFieldLabel = uilabel(app.UIFigure);
            app.KOLOR1MAXEditFieldLabel.Text = 'KOLOR1-MAX';
            app.KOLOR1MAXEditField = uieditfield(app.UIFigure, 'numeric');
            app.KOLOR1MAXEditField.Value = 60;
            app.KOLOR1MINEditFieldLabel = uilabel(app.UIFigure);
            app.KOLOR1MINEditFieldLabel.Text = 'KOLOR1-MIN';
            app.KOLOR1MINEditField = uieditfield(app.UIFigure, 'numeric');
            app.KOLOR1MINEditField.Value = 300;
            app.KOLOR2MINEditFieldLabel = uilabel(app.UIFigure);
            app.KOLOR2MINEditFieldLabel.Text = 'KOLOR2-MIN';
            app.KOLOR2MINEditField = uieditfield(app.UIFigure, 'numeric');
            app.KOLOR2MINEditField.Value = 240;
            app.KOLOR2MAXEditFieldLabel = uilabel(app.UIFigure);
            app.KOLOR2MAXEditFieldLabel.Text = 'KOLOR2-MAX';
            app.KOLOR2MAXEditField = uieditfield(app.UIFigure, 'numeric');
            app.KOLOR2MAXEditField.Value = 120;
            app.ADRESURIEditFieldLabel = uilabel(app.UIFigure);
            app.ADRESURIEditFieldLabel.Text = 'ADRES URI';
            app.ADRESURIEditField = uieditfield(app.UIFigure, 'text');
            app.ADRESURIEditField.Value = 'http://localhost:5001';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.PozycjaLabel = uilabel(app.UIFigure);
            app.PozycjaLabel.Text = 'Pozycja';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.SterowanieLabel = uilabel(app.UIFigure);
            app.SterowanieLabel.Text = 'Sterowanie';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2Label = uilabel(app.UIFigure);
            app.Lamp_2Label.Text = '';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '';
            app.RatioLabel = uilabel(app.UIFigure);
            app.RatioLabel.Text = 'Ratio';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'HueScale.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ICK_SENSORS_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end